classdef GUI4GA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GUI4GALabel                    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        PleasechooseaselectionmethodLabel  matlab.ui.control.Label
        PleasechooseaselectionmethodDropDown  matlab.ui.control.DropDown
        InitializationLabel            matlab.ui.control.Label
        DimensionEditFieldLabel        matlab.ui.control.Label
        DimensionEditField             matlab.ui.control.NumericEditField
        UpperBoundEditFieldLabel       matlab.ui.control.Label
        UpperBoundEditField            matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel       matlab.ui.control.Label
        LowerBoundEditField            matlab.ui.control.NumericEditField
        PopSizeEditFieldLabel          matlab.ui.control.Label
        PopSizeEditField               matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        IterationsEditField            matlab.ui.control.NumericEditField
        FitnessFunctionLabel           matlab.ui.control.Label
        Pleasenameyourfunctionasfit_funinthecurrentfolderLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        FoundOptiimumValueEditFieldLabel  matlab.ui.control.Label
        FoundOptiimumValueEditField    matlab.ui.control.NumericEditField
        ElitismRateEditFieldLabel      matlab.ui.control.Label
        ElitismRateEditField           matlab.ui.control.NumericEditField
        CrossoverRateEditFieldLabel    matlab.ui.control.Label
        CrossoverRateEditField         matlab.ui.control.NumericEditField
        MutationRateEditFieldLabel     matlab.ui.control.Label
        MutationRateEditField          matlab.ui.control.NumericEditField
        ExportButton                   matlab.ui.control.Button
        OptimizationMethodButtonGroup  matlab.ui.container.ButtonGroup
        MinimizationButton             matlab.ui.control.RadioButton
        MaximizationButton             matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        place
        bests
    end
    
    methods (Access = private)
        
        function [Optcost,Value,Bests]=GA(app,selection,mthd)
            popsize = app.PopSizeEditField.Value;
            maxIter = app.IterationsEditField.Value;
            pc = app.CrossoverRateEditField.Value;
            pm = app.MutationRateEditField.Value;
            elitism_rate = app.ElitismRateEditField.Value;
            ul = app.UpperBoundEditField.Value;
            ll = app.LowerBoundEditField.Value;
            dim = app.DimensionEditField.Value;
            
            n = dim;
            ff = 'fit_fun';
            t_size = 3;
            %%%%%%%%%%%% Initialization %%%%%%%%%%%%%%%%

            % Creating population having default values
            pop = ones(popsize,n);

            % Using ul and ll, we create our population
            Lb = ll*ones(1,n); 
            Ub = ul*ones(1,n);
            for i=1:popsize
                pop(i,:) = Lb+(Ub-Lb).*rand(size(Lb));
            end

            % Calculate fitness values of each individual
            cost = feval(ff,pop);

            count = maxIter;
            % For plotting
            % iter = 1 : maxIter;
            Bests = zeros(maxIter,1);

            while maxIter > 0
            %%%%%%%%%%%%%%% Selection %%%%%%%%%%%%%%%
                switch selection
                    case 1
                        [first_mate,second_mate] = roulette(app,pop,n,cost);
                    case 2
                        [first_mate,second_mate] = ranking(app,pop,n,cost,mthd);
                    case 3
                        [first_mate,second_mate] = tournament(app,pop,n,t_size,cost,mthd);
                end
    
                %%%%%%%%%%%%%%% CrossOver %%%%%%%%%%%%%%%%
                % rpc decides what individual is going to mate
                rpc = rand(popsize,1);
                % Individuals not attend the crossover
                ind_co = find(rpc > pc);
                newpop = first_mate./2+second_mate./2+(1/2+0.1).*abs(second_mate - first_mate).*(2*rand-1);
                newpop(ind_co,:) = first_mate(ind_co,:);

                %%%%%%%%%%%%%%% Mutation %%%%%%%%%%%%%%%%%%
                % Creating new population for individuals who are going to mutate
                mut_pop = ones(popsize,n);
                for i = 1 : popsize
                    mut_pop(i,:) = Lb+(Ub-Lb).*rand(size(Lb));
                end
                 % We give mgpr,number of mutated gene probability, value to decide how many genes are going to mutate
                mgpr = 1-pm;
                mpm = rand(popsize,1);
                indices = find(mpm <= mgpr);
                mut_matrix = randi([0 1],popsize,n);

                newpop(indices,:) = mut_matrix(indices,:).*mut_pop(indices,:);
    
                maxIter=maxIter-1;
    
                %%%%%%%%%%%%%%% Elitism %%%%%%%%%%%%%%%%%%%
                if mthd==1
                    [~,in1]=sort(cost);
                else
                    [~,in1]=sort(cost,'descend');
                end
                n_elit=round(size(in1,1)*elitism_rate);
                if n_elit<1
                    n_elit=1;
                end
                part1 = pop(in1(1:n_elit),:);
                cost_new = feval(ff,newpop);
                if mthd==1
                    [~,in2]=sort(cost_new);
                else
                    [~,in2]=sort(cost_new,'descend');
                end
                part2 = newpop(in2(1:popsize-n_elit),:);
                pop = [part1;part2];
    
                % Finding the best for each iteration
                index = count-maxIter;
                cost = feval(ff,pop);
                if mthd==1
                    [val,~] = min(cost);
                else
                    [val,~] = max(cost);
                end
                Bests(index) = val;
            end
            lastcost = feval(ff,pop);
            if mthd==1
                [Optcost,in] = min(lastcost);
            else
                [Optcost,in] = max(lastcost);
            end
            Value = pop(in,:);
        end
        
        % Roulette Wheel
        function [first_mate,second_mate]=roulette(app,pop,n,cost)
            cost_m = 1./cost;
            popsize = app.PopSizeEditField.Value;
            % Probabilities of the individuals
            prob_select = cost_m/sum(cost_m);
            first_mate = zeros(popsize,n);
            second_mate = zeros(popsize,n);
            % Cumulative probability
            all_prob = cumsum(prob_select);
            for j = 1 : 2
                for k = 1 : popsize
                    winner = nnz(all_prob < rand)+1;
                    % To ensure the winner value is less than or equal to
                    % the popsize value
                    while winner > popsize
                        winner = nnz(all_prob < rand)+1;
                    end
                    if j == 1
                        first_mate(k,:) = pop(winner,:);
                    else
                        second_mate(k,:) = pop(winner,:);
                    end
                end
            end
        end
        
        % Linear Rank
        function [first_mate,second_mate]=ranking(app,pop,n,cost,mthd)
            popsize = app.PopSizeEditField.Value;
            first_mate = zeros(popsize,n);
            second_mate = zeros(popsize,n);
            if mthd ==1
                [ ~,sorted] = sort(cost);
            else
                [ ~,sorted] = sort(cost,'descend');
            end
            
            rank = zeros(popsize,1);
            for i = 1 : popsize
                rank(sorted(i)) = popsize+1-i;
            end
            cum_rank = cumsum(rank);
            for j = 1 : 2
                for k = 1 : popsize
                    r = min(cum_rank)+ (max(cum_rank)-min(cum_rank)).*rand;
                    winner = nnz(cum_rank<r)+1;
                    % To ensure the winner value is less than or equal to
                    % the popsize value
                    while winner > popsize
                        r = min(cum_rank)+ (max(cum_rank)-min(cum_rank)).*rand;
                        winner = nnz(cum_rank<r)+1;
                    end
                    if j == 1
                        first_mate(k,:) = pop(winner,:);
                    else
                        second_mate(k,:) = pop(winner,:);
                    end
                end
            end
        end
        % Standard Tournament
        function [first_mate,second_mate]=tournament(app,pop,n,tournamentsize,cost,mthd)
            attend1 = zeros(tournamentsize,1);
            attend2 = zeros(tournamentsize,1);
            popsize = app.PopSizeEditField.Value;
            first_mate = ones(popsize,n);
            second_mate = ones(popsize,n);
            for j = 1 : popsize
                for k = 1 : tournamentsize
                    attend1(k) = ceil((popsize-1)*rand+1);
                    attend2(k) = ceil((popsize-1)*rand+1);
                end
                if mthd ==1
                    winner1 = find(cost==min(cost(attend1)));
                    winner2 = find(cost==min(cost(attend2)));
                else
                    winner1 = find(cost==max(cost(attend1)));
                    winner2 = find(cost==max(cost(attend2)));
                end
                % If we have, more than 1 winner
                first_mate(j,:) = pop(winner1(1),:);
                second_mate(j,:) = pop(winner2(1),:);
            end
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            switch app.PleasechooseaselectionmethodDropDown.Value
                case 'Roulette Wheel'
                    selection = 1;
                    tt = "Result of Roulette Wheel";
                case 'Linear Ranking'
                    selection = 2;
                    tt = "Result of Linear Ranking";
                case 'Standard Tournament'
                    selection = 3;
                    tt = "Result of Standard Tournament";
            end
            
            if app.MinimizationButton.Value
                mthd = 1;
            else
                mthd = 2;
            end
            
            [app.FoundOptiimumValueEditField.Value,app.place,app.bests]=GA(app,selection,mthd);
            plot(app.UIAxes,1:app.IterationsEditField.Value,app.bests,"r:")
            title(app.UIAxes, tt)
            xlabel(app.UIAxes, "Iterations")
            ylabel(app.UIAxes, "Bests")
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin("base","Opt_cost", app.FoundOptiimumValueEditField.Value)
            assignin("base", "Opt_cost_loc",app.place)
            assignin("base","Bests",app.bests)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GUI4GALabel = uilabel(app.UIFigure);
            app.GUI4GALabel.Text = 'GUI4GA';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PleasechooseaselectionmethodLabel = uilabel(app.UIFigure);
            app.PleasechooseaselectionmethodLabel.Text = 'Please choose a selection method :';
            app.PleasechooseaselectionmethodDropDown = uidropdown(app.UIFigure);
            app.PleasechooseaselectionmethodDropDown.Items = {'Roulette Wheel', 'Linear Ranking', 'Standard Tournament'};
            app.PleasechooseaselectionmethodDropDown.Value = 'Roulette Wheel';
            app.InitializationLabel = uilabel(app.UIFigure);
            app.InitializationLabel.Text = 'Initialization';
            app.DimensionEditFieldLabel = uilabel(app.UIFigure);
            app.DimensionEditFieldLabel.Text = 'Dimension';
            app.DimensionEditField = uieditfield(app.UIFigure, 'numeric');
            app.DimensionEditField.ValueDisplayFormat = '%.0f';
            app.UpperBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.LowerBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopSizeEditFieldLabel = uilabel(app.UIFigure);
            app.PopSizeEditFieldLabel.Text = 'PopSize';
            app.PopSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = '# Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FitnessFunctionLabel = uilabel(app.UIFigure);
            app.FitnessFunctionLabel.Text = 'Fitness Function';
            app.Pleasenameyourfunctionasfit_funinthecurrentfolderLabel = uilabel(app.UIFigure);
            app.Pleasenameyourfunctionasfit_funinthecurrentfolderLabel.Text = {'*Please name your fitness function as "fit_fun" and put it'; 'in the current folder'};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FoundOptiimumValueEditFieldLabel = uilabel(app.UIFigure);
            app.FoundOptiimumValueEditFieldLabel.Text = 'Found Optiimum Value :';
            app.FoundOptiimumValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElitismRateEditFieldLabel = uilabel(app.UIFigure);
            app.ElitismRateEditFieldLabel.Text = 'Elitism Rate';
            app.ElitismRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.CrossoverRateEditFieldLabel = uilabel(app.UIFigure);
            app.CrossoverRateEditFieldLabel.Text = 'Crossover Rate';
            app.CrossoverRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.MutationRateEditFieldLabel = uilabel(app.UIFigure);
            app.MutationRateEditFieldLabel.Text = 'Mutation Rate';
            app.MutationRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.OptimizationMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.OptimizationMethodButtonGroup.Title = 'Optimization Method';
            app.MinimizationButton = uiradiobutton(app.OptimizationMethodButtonGroup);
            app.MinimizationButton.Text = 'Minimization';
            app.MinimizationButton.Value = true;
            app.MaximizationButton = uiradiobutton(app.OptimizationMethodButtonGroup);
            app.MaximizationButton.Text = 'Maximization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI4GA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end